classdef frame_generator_config < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        QuietTimePanel                matlab.ui.container.Panel
        QuietTimeSlider               matlab.ui.control.Slider
        QuietTimesEditFieldLabel      matlab.ui.control.Label
        QuietTimesEditField           matlab.ui.control.NumericEditField
        AdvancedConfigurationLabel    matlab.ui.control.Label
        SamplingFrequencyPanel        matlab.ui.container.Panel
        SamplingFrequencySlider       matlab.ui.control.Slider
        SamplingFrequencyHzEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencyHzEditField  matlab.ui.control.NumericEditField
        OutputFileTypeDropDownLabel   matlab.ui.control.Label
        OutputFileTypeDropDown        matlab.ui.control.DropDown
        BitsPerSampleLabel            matlab.ui.control.Label
        BitsPerSampleDropDown         matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        CallingApp % Description
        bits_per_sample=8;
        sampling_frequency=0.0;
        quiet_time=0.0;
        output_file_type='wav';
        type_of_output =0;
        
    end
    
    methods (Access = public)
        
        function SetupBitsPerSampleDD(app)
            items={}
                switch (app.OutputFileTypeDropDown.Value)
                    case 'wav'
                        items={'8','16','24','32','64'}
                        app.BitsPerSampleDropDown.Enable='on';                        
                    case 'flac'
                        items={'8','16','24'}
                        app.BitsPerSampleDropDown.Enable='on';
                    otherwise
                        app.BitsPerSampleDropDown.Enable='off';
                end
                app.BitsPerSampleDropDown.Items =items
                if class(app.bits_per_sample) ~= "string" && class(app.bits_per_sample) ~="char"
                    if ismember(int2str(app.bits_per_sample),items)
                app.BitsPerSampleDropDown.Value= int2str(app.bits_per_sample);    
                    end
                else
                    if ismember(app.bits_per_sample,items)
                app.BitsPerSampleDropDown.Value= app.bits_per_sample;    
                    end
                end
                
                
        end
        function InitSetup(app)
            app.QuietTimesEditField.Value = app.quiet_time;
            app.QuietTimeSlider.Value = app.quiet_time;
            app.SamplingFrequencyHzEditField.Value = app.sampling_frequency;    
            app.SamplingFrequencySlider.Value = app.sampling_frequency;
            app.OutputFileTypeDropDown.Value = app.output_file_type;             
            %app.BitsPerSampleDropDown.Value= int2str(app.bits_per_sample);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, sampling_frequency, quiet_time, bits_per_sample, output_file_type, type_of_output)
            app.bits_per_sample = bits_per_sample;
            app.CallingApp = mainapp;
            app.quiet_time = quiet_time;
            app.sampling_frequency = sampling_frequency;            
            app.output_file_type = output_file_type;
            InitSetup(app);
            SetupBitsPerSampleDD(app);
            %app.BitsPerSampleDropDown = bits_per_sample;
            
        end

        % Value changed function: OutputFileTypeDropDown
        function OutputFileTypeDropDownValueChanged(app, event)
            value = app.OutputFileTypeDropDown.Value;
            app.output_file_type = value;
            SetupBitsPerSampleDD(app);            
        end

        % Value changed function: QuietTimesEditField
        function QuietTimesEditFieldValueChanged(app, event)
            value = app.QuietTimesEditField.Value;
            app.QuietTimeSlider.Value=value;
            app.quiet_time = value;                      
        end

        % Value changed function: QuietTimeSlider
        function QuietTimeSliderValueChanged(app, event)
            value = app.QuietTimeSlider.Value;
            app.quiet_time=value;
            app.QuietTimesEditField.Value=value;
            
        end

        % Value changing function: QuietTimeSlider
        function QuietTimeSliderValueChanging(app, event)
            changingValue = event.Value;
            app.quiet_time=changingValue;
            app.QuietTimesEditField.Value=changingValue;
            
        end

        % Value changed function: SamplingFrequencyHzEditField
        function SamplingFrequencyHzEditFieldValueChanged(app, event)
            value = app.SamplingFrequencyHzEditField.Value;
            app.sampling_frequency=value;
            app.SamplingFrequencySlider.Value = value;  
            
        end

        % Value changed function: SamplingFrequencySlider
        function SamplingFrequencySliderValueChanged(app, event)
            value = app.SamplingFrequencySlider.Value;
            app.sampling_frequency=value;
            app.SamplingFrequencyHzEditField.Value=value;
            
        end

        % Value changing function: SamplingFrequencySlider
        function SamplingFrequencySliderValueChanging(app, event)
            changingValue = event.Value;
            app.sampling_frequency=changingValue;
            app.SamplingFrequencyHzEditField.Value=changingValue;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           
            app.CallingApp.settingsBtn.Enable ='on';
            %app.CallingApp.AdvancedSettingsMenu.Enable = 'on';
            app.CallingApp.settingsBtn.Enable = 'on';
            app.CallingApp.bits_per_sample= app.bits_per_sample;
            app.CallingApp.quiet_time= app.quiet_time;
            app.CallingApp.output_file_type = app.output_file_type;
            app.CallingApp.sampling_frequency =  app.sampling_frequency;
            delete(app)
        end

        % Value changed function: BitsPerSampleDropDown
        function BitsPerSampleDropDownValueChanged(app, event)
            value = app.BitsPerSampleDropDown.Value;
            app.bits_per_sample =value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.QuietTimePanel = uipanel(app.UIFigure);
            app.QuietTimePanel.Title = 'Quiet Time';
            app.QuietTimeSlider = uislider(app.QuietTimePanel);
            app.QuietTimeSlider.Limits = [0 1000];
            app.QuietTimeSlider.ValueChangedFcn = createCallbackFcn(app, @QuietTimeSliderValueChanged, true);
            app.QuietTimeSlider.ValueChangingFcn = createCallbackFcn(app, @QuietTimeSliderValueChanging, true);
            app.QuietTimesEditFieldLabel = uilabel(app.QuietTimePanel);
            app.QuietTimesEditFieldLabel.Text = 'Quiet Time (s)';
            app.QuietTimesEditField = uieditfield(app.QuietTimePanel, 'numeric');
            app.QuietTimesEditField.ValueChangedFcn = createCallbackFcn(app, @QuietTimesEditFieldValueChanged, true);
            app.AdvancedConfigurationLabel = uilabel(app.UIFigure);
            app.AdvancedConfigurationLabel.Text = 'Advanced Configuration';
            app.SamplingFrequencyPanel = uipanel(app.UIFigure);
            app.SamplingFrequencyPanel.Title = 'Sampling Frequency';
            app.SamplingFrequencySlider = uislider(app.SamplingFrequencyPanel);
            app.SamplingFrequencySlider.Limits = [0 50000];
            app.SamplingFrequencySlider.MajorTicks = [0 10000 20000 30000 40000 50000];
            app.SamplingFrequencySlider.MajorTickLabels = {'0', '10000', '20000', '30000', '40000', '50000'};
            app.SamplingFrequencySlider.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequencySliderValueChanged, true);
            app.SamplingFrequencySlider.ValueChangingFcn = createCallbackFcn(app, @SamplingFrequencySliderValueChanging, true);
            app.SamplingFrequencyHzEditFieldLabel = uilabel(app.SamplingFrequencyPanel);
            app.SamplingFrequencyHzEditFieldLabel.Text = 'Sampling Frequency (Hz)';
            app.SamplingFrequencyHzEditField = uieditfield(app.SamplingFrequencyPanel, 'numeric');
            app.SamplingFrequencyHzEditField.ValueDisplayFormat = '%.0f';
            app.SamplingFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequencyHzEditFieldValueChanged, true);
            app.OutputFileTypeDropDownLabel = uilabel(app.UIFigure);
            app.OutputFileTypeDropDownLabel.Text = 'Output File Type';
            app.OutputFileTypeDropDown = uidropdown(app.UIFigure);
            app.OutputFileTypeDropDown.Items = {'wav', 'ogg', 'flac', 'mp4'};
            app.OutputFileTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputFileTypeDropDownValueChanged, true);
            app.OutputFileTypeDropDown.Value = 'wav';
            app.BitsPerSampleLabel = uilabel(app.UIFigure);
            app.BitsPerSampleLabel.Text = 'Bits Per Sample';
            app.BitsPerSampleDropDown = uidropdown(app.UIFigure);
            app.BitsPerSampleDropDown.ValueChangedFcn = createCallbackFcn(app, @BitsPerSampleDropDownValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = frame_generator_config(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end