classdef oceanus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        CarrierFrequencySelectorPanel   matlab.ui.container.Panel
        FrequencySliderHzLabel          matlab.ui.control.Label
        FrequencySliderHz               matlab.ui.control.Slider
        CarrierFrequencyEditField       matlab.ui.control.NumericEditField
        HzLabel                         matlab.ui.control.Label
        GenerateAudioButton             matlab.ui.control.Button
        settingsBtn                     matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        HTML                            matlab.ui.control.HTML
        DataLabel                       matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        GenerateRandomDataButton        matlab.ui.control.Button
        VolumeSliderLabel               matlab.ui.control.Label
        VolumeSlider                    matlab.ui.control.Slider
        SaveaudiofileButton             matlab.ui.control.Button
        OutputDeviceDropDownLabel       matlab.ui.control.Label
        OutputDeviceDropDown            matlab.ui.control.DropDown
        TransmitterLabel                matlab.ui.control.Label
        ReceiverLabel                   matlab.ui.control.Label
        ReceiveDecodeButton             matlab.ui.control.Button
        InputDeviceDropDownLabel        matlab.ui.control.Label
        InputDeviceDropDown             matlab.ui.control.DropDown
        Label_2                         matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        LoadedFilesListBoxLabel         matlab.ui.control.Label
        LoadedFilesListBox              matlab.ui.control.ListBox
        removeFileFromListBox           matlab.ui.control.Button
        DecodeSelectedFilesButton       matlab.ui.control.Button
        LoadAudioButton                 matlab.ui.control.Button
        SaveDecodingLogsButton          matlab.ui.control.Button
        DecodingLoader                  matlab.ui.control.HTML
        SelectALLButton                 matlab.ui.control.Button
        maxcyclesEditFieldLabel         matlab.ui.control.Label
        maxcyclesEditField              matlab.ui.control.NumericEditField
        bitspersampleDropDownLabel      matlab.ui.control.Label
        rx_bits_per_sample_DropDown     matlab.ui.control.DropDown
        ReceiverSamplingFrequencyPanel  matlab.ui.container.Panel
        rx_sampling_rate_slider         matlab.ui.control.Slider
        rx_sampling_rate_EditField      matlab.ui.control.NumericEditField
        CarrierFrequencyEditField_2Label  matlab.ui.control.Label
        rx_CarrierFrequencyEditField    matlab.ui.control.NumericEditField
        StopReceivingDecodingButton     matlab.ui.control.Button
        DecodingLoader_2                matlab.ui.control.HTML
        UpSampleEditFieldLabel          matlab.ui.control.Label
        UpSampleEditField               matlab.ui.control.NumericEditField
        DownSampleEditFieldLabel        matlab.ui.control.Label
        DownSampleEditField             matlab.ui.control.NumericEditField
        DopplerShiftEditFieldLabel      matlab.ui.control.Label
        DopplerShiftEditField           matlab.ui.control.NumericEditField
        StepEditFieldLabel              matlab.ui.control.Label
        StepEditField                   matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        FramesSpinnerLabel              matlab.ui.control.Label
        FramesSpinner                   matlab.ui.control.Spinner
        GenerateFramesButton            matlab.ui.control.Button
        OperatingModeDropDownLabel      matlab.ui.control.Label
        OperatingModeDropDown           matlab.ui.control.DropDown
        Label_3                         matlab.ui.control.Label
        SaveGeneratedFramesDataButton   matlab.ui.control.Button
        AddSNRPanel                     matlab.ui.container.Panel
        loadsignalButton                matlab.ui.control.Button
        minSNREditField                 matlab.ui.control.NumericEditField
        maxSNREditField                 matlab.ui.control.NumericEditField
        stepSNREditField                matlab.ui.control.NumericEditField
        GenerateSignalsButton           matlab.ui.control.Button
        minSNRLabel                     matlab.ui.control.Label
        maxSNRLabel                     matlab.ui.control.Label
        stepSNRLabel                    matlab.ui.control.Label
        SNRListBox                      matlab.ui.control.ListBox
        Menu                            matlab.ui.container.Menu
        Menu_2                          matlab.ui.container.Menu
        SaveConfigurationMenu           matlab.ui.container.Menu
        LoadConfigurationMenu           matlab.ui.container.Menu
        SettingsMenu                    matlab.ui.container.Menu
        ChooseOutputMenu                matlab.ui.container.Menu
        FileMenu                        matlab.ui.container.Menu
        AudioSinkMenu                   matlab.ui.container.Menu
        AdvancedSettingsMenu            matlab.ui.container.Menu
        Menu3                           matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    %transmitter
    properties (Access = public)
        carrier_frequency =209.0;  % Hz
        quiet_time =0.0;         % seconds
        sampling_frequency =12000.00; % samples/sec
        bits_per_sample ="16";
        output_file_name ="";
        type_of_output =0; % 0 for file & 1 for audio sink
        output_file_type ="wav"; %   wav, flac etc.
        mode = 0; % 0 for TX &  1 for RX 
        qsig=[];
        isig=[];
        y=[];
        framesDataBits=[];
        player= audioplayer.empty;
        message="";
        outputAudioDeviceId=0;
        inputAudioDeviceId=0;
        vMeter = "";%VolumeMeter(GridLayout);
        %recorder =  audioDeviceReader;
        spssymbol =[128, 256, 512, 1024, 2048, 2560]
        SymMode=1;
        rx_number_of_channels=1;
        rx_sampling_rate=12000;
        rx_bits_per_sample=8;
        rx_CarrierFrequency =209.0; %Hz
        isrecording= false;
        interval = [1 2 4 8 16 20];
        rx_buffer = dsp.AsyncBuffer.empty;
        dwnConv = dsp.DigitalDownConverter;
        overruns =[]
        rx_decoded_data =[]
    end
    
    %receiver
    properties(Access = public)
        files=[]
        SNR_files=[]
        
    end
    
    properties (Access = private)
        DialogApp % Description
    end
    
    methods (Access = public)
        
        %this function is to be removed now its kept just for reference
%         function []=c2toWave(app, idat, qdat, oFile,f,toffset,oSps,BitsPerSample)
%             % Convert a baseband signal (".c2" file format, complex envelope) to
%             % broadband signal (".wav" file format).
%             %
%             % Author: Michel Barbeau, Carleton University - Modified by Ahmad Traboulsi, Carleton University
%             
%             % Version: DEC 30, 2020
%             
%             % iFile = name of ".c2" file (".c2" extension assumed) NO NEED FOR iFile
%             
%             % oFile = output file name
%         	% f = frequency vs time (in Hz)
%             % t = time points
%             % output is over a 120-second time interval, with 111-second frame
%             % toffset = random prefix, in seconds, in [0,9]
%             % oSps =  output file sampling frequency
%             % upSampling = up sampling factor
%             %
%             %
%             %%% read baseband signal from ".c2" file
%             %
%             %fileID = fopen([iFile '.c2'],'r');
%             % read file name (14 characters)
%             %fname=fread(fileID,[1 14],'char');
%             % read WSPR type (integer) 2="2 minute mode"
%             %type=fread(fileID,[1 1],'int');
%             % read frequency (double)
%             %dfreq=fread(fileID,[1 1],'double');
%             % read data (float)
%             %buffer=fread(fileID,[2 45000],'float');
%             % close the input file
%             %fclose(fileID);
%             % in-phase
%             
%             %idat=buffer(1,:);
%             % quadrature
%             %qdat=buffer(2,:);
%             %
%             %%% up conversion
%             %
%             % up sampling factor
%             upSampling = oSps / 375;
%             % resample the baseband signal for the ".wav" format
%             x=resample(complex(idat,qdat),upSampling,1);
%             % number of time points, over a 120-second interval
%             xl = oSps*120;
%             % generate time points
%             T = 120; % period (seconds)
%             dt = T/xl; % sample period (second)
%             t = 0:dt:T-dt;
%             % modulation
%             app.y = x.*exp(1i*(2*pi*f.*t));
%             % normalize in the [-1 1] range
%             app.y = app.y/(max(abs(min(app.y)),max(app.y)));
%             % add a random noise prefix during time offeset
%             r=rand(1,round(toffset*oSps));
%             app.y =[r(:);app.y];
%             % write the wave file
%             app.player = audioplayer(real(app.y), oSps,BitsPerSample);
%             audiowrite(oFile,real(app.y),oSps,'BitsPerSample',BitsPerSample);
%         end
        
        %NOT USED - only for reference - to be removed later
%         function [] = FrameGenerator(app)
%             % Generate frames, coded in ".wav" files
%             % Author: Michel Barbeau, Carleton University
%             % Version: August 20, 2019
%             %
%             clear;
%             %
%             %%% PARAMETERS
%             %
%             % 1. Input file name (".c2" file format, complex envelope @ 375 samples/sec)
%             iFile = '150613_1920';
%             fprintf('Input file name: %s.c2\n', iFile)
%             % 2. Carrier frequency
%             f = 209; % Hz
%             fprintf('Carrier frequency: %d Hz\n', f)
%             % 3. Time offset (random quiet time prefix, in seconds, in [0,9])
%             toffset = 0; % second
%             fprintf('Time offset: %d sec.\n', toffset)
%             % 4. Output file sampling frequency
%             oSps = 4500; % samples/sec (a multiple of 375, e.g. 6K, 12K, 24K, 48K, 96K)
%             fprintf('Output file sampling frequency: %d sps\n', oSps);
%             % 5. Output file name (broadband signal in ".wav" format)
%             oFile = int2str(f)+"_"+int2str(oSps)+".wav"; % use frequency number and sampling frequency
%             fprintf('Output file name: %s\n', oFile);
%             % 6. Bits per sample
%             BitsPerSample = 16;
%             %
%             %%% CONVERSION
%             %
%             c2toWave(app, iFile, oFile, f, toffset, oSps, BitsPerSample);
%         end
%            
        function []= SetupMode(app)            
                app.Panel.Visible ='on';                
                app.ChooseOutputMenu.Visible = 'on';
                app.ChooseOutputMenu.Enable = 'on';           
        end
        
        function SetupOutput(app)
            if app.type_of_output == 0 %file
                app.FileMenu.Text = 'File ✓';
                app.AudioSinkMenu.Text ='Audio Sink';
                %app.OutputFileNameEditField.Visible = true;
                %app.OutputFileNameEditFieldLabel.Visible=true;                
            else
                app.FileMenu.Text = 'File';
                app.AudioSinkMenu.Text ='Audio Sink ✓';
                %app.OutputFileNameEditField.Visible = false;
                %app.OutputFileNameEditFieldLabel.Visible=false;
            end
        end
    end
    
    methods (Access = public)
        
%         function [insig, qdsig] = wspr(app, message, snr)
%         if not(libisloaded('wsprsim'))
%         loadlibrary('wsprsim')
%         end
%         insig = libpointer('doublePtr', zeros(1,45000));
%         qdsig = libpointer('doublePtr', zeros(1,45000));
%         calllib('wsprsim', 'wsprfunc',message, snr, insig, qdsig)
%         end


        function symbols = uwspr_framer(app, data)
            % Input
            %   data = Binary sequence, an array of zeros and ones
            % Output
            %   symbols = channel symbols, an arry of 162 items of 0, 1, 2 or 3
            %
            % Convolutional coding
            K = 32;
            % Laylan-Lushbaugh code
            % g=[dec2bin(hex2dec('f2d05351')); dec2bin(hex2dec('e4613c47'))];
            g=[1 1 1 1 0 0 1 0 1 1 0 1 0 0 0 0 0 1 0 1 0 0 1 1 0 1 0 1 0 0 0 1;
                1 1 1 0 0 1 0 0 0 1 1 0 0 0 0 1 0 0 1 1 1 1 0 0 0 1 0 0 0 1 1 1 ];
            % initial shift register with zeros
            reg = zeros(1,K);
            % init output sequence
            cs = [];
            % % generate coded bits
            for i=1:length(data)
                % update register (encoding state)
                reg = [reg(2:end) data(i)];
                c = covcoder(reg, g);
                cs = [ cs c ];
            end
            % interleave the code bits
            ics = interleave(app, cs);
            % synchronization bits
            s = [1 1 0 0 0 0 0 0 1 0 0 0 1 1 1 0 0 0 1 0 0 1 0 1 1 1 1 0 0 0 0 0 0 0 1 0 0 1 0 1 ...
                0 0 0 0 0 0 1 0 1 1 0 0 1 1 0 1 0 0 0 1 1 0 1 0 0 0 0 1 1 0 1 0 1 0 1 0 1 0 0 1 ...
                0 0 1 0 1 1 0 0 0 1 1 0 1 0 1 0 0 0 1 0 0 0 0 0 1 0 0 1 0 0 1 1 1 0 1 1 0 0 1 1 ...
                0 1 0 0 0 1 1 1 0 0 0 0 0 1 0 1 0 0 1 1 0 0 0 0 0 0 0 1 1 0 1 0 1 1 0 0 0 1 1 0 ...
                0 0];
            symbols = 2*ics + s; % in every symbol, MSB is code bit, LSB is synchro bit
        end
        function c = covcoder(reg, code)            
            % Inputs:
            %   reg = input register (coding state before)
            %   code = convolutional code
            % Outputs:
            %   [c2 c1] = Code bits
            % generate coded bits
            c2 = mod(sum(and(reg,code(1,:))),2);
            c1 = mod(sum(and(reg,code(2,:))),2);
            c = [c2 c1];
        end        
        function isymbols = interleave(app, symbols)
            isymbols = zeros(1,length(symbols));
            interleave_seq = [ 0 128 64 32 160 96 16 144 80 48 112 8 136 72 40 104 24 152 88 56 120 4 132 68 36 100 20 148 84 52 116 12 140 76 44 108 28 156 92 60 124 2 130 66 34 98 18 146 82 50 114 10 138 74 42 106 26 154 90 58 122 6 134 70 38 102 22 150 86 54 118 14 142 78 46 110 30 158 94 62 126 1 129 65 33 161 97 17 145 81 49 113 9 137 73 41 105 25 153 89 57 121 5 133 69 37 101 21 149 85 53 117 13 141 77 45 109 29 157 93 61 125 3 131 67 35 99 19 147 83 51 115 11 139 75 43 107 27 155 91 59 123 7 135 71 39 103 23 151 87 55 119 15 143 79 47 111 31 159 95 63 127 ];
            for i=1:length(symbols)
                isymbols(interleave_seq(i)+1) = symbols(i);
            end
        end
       
        
        function y = uwspr_modulater(app, symbols, spsymbol)
            % Input
            %   symbols = channel symbols, an array of 162 items in 0, 1, 2 or 3
            %   spsymbol = samples per symbol (256, 512, 1024, 2048, 2560)
            % Output
            %   y = complex envelope of baseband signal
            delta = 1/375; % sample period (in fraction of second)
            % frequency offsets for symbols 00, 01, 10 an 11 (in Hertz)
            freqs = [-2.2 -0.73 0.73 2.2];
            y = zeros(1,length(symbols)*spsymbol);
            for j=1:length(symbols) % channel symbol loop
                for k=1:spsymbol % symbol sample loop
                    t = (j-1)*spsymbol+k; % time instant (in samples)
                    % vector of modulated signal rotates clockwise
                    y(t) = exp(-1i*2*pi*freqs(symbols(j)+1)*t*delta);
                end
            end
        end
        
        function generate_signal(app)
            %get data
            K=32;
            data = reshape(dec2bin(hex2dec(app.message),50).',1,[]);
            %data = regexprep(data,'^0*','')
            data = data - '0';
            data = data(1:50);
            data = [data zeros(1,K-1)]
            symbols= uwspr_framer(app, data);
            % Get complex envelop of the baseband signal
            y= uwspr_modulater(app, symbols, app.spssymbol(app.SymMode));                                                                    
            
            % Generate  file 
            %
            % 2. Carrier frequency
            f = app.carrier_frequency; % Hz
            % 3. Time offset (random quiet time prefix, in seconds, in [0,9])
            toffset = app.quiet_time; % second          
            % 4. Output file sampling frequency
            oSps = app.sampling_frequency; % samples/sec (a multiple of 375, e.g. 6K, 12K, 24K, 48K, 96K)            
            % 5. Output file name (broadband signal in ".wav" format)
            oFile = int2str(f)+"_"+int2str(oSps)+".wav"; % use frequency number and sampling frequency           
            % 6. Bits per sample
            BitsPerSample = app.bits_per_sample;
            %%% up conversion
            %
            % extend the signal to [2 4 8 16 20] minutes
            z = [y zeros(1,375*app.interval(app.SymMode)*60-length(y))];
            % up sampling factor
            upSampling = oSps / 375;
            % resample the baseband signal for the ".wav" format
            x = resample(z,upSampling,1);
            % number of time points,
            xl = oSps*app.interval(app.SymMode)*60;
            % generate time points
            T = app.interval(app.SymMode)*60; % period (seconds)
            dt = T/xl; % sample period (second)
            t = 0:dt:T-dt;
            % modulation
            yy = x.*exp(-1i*(2*pi*f.*t));
            % normalize in the [-1 1] range
            yn = yy/( max( abs(min(yy)), max(yy) ) );
            % add a random noise prefix during time offset
            % r=rand(1,round(toffset*oSps)); ynn = [ r  yn];
            ynn = [zeros(1,375*toffset) yn];
            app.y= ynn;
            if class(BitsPerSample) == "string" || class(BitsPerSample) =="char"
            app.player = audioplayer(real(app.y), oSps,str2num(BitsPerSample));
            else
            app.player = audioplayer(real(app.y), oSps,BitsPerSample);
            end

            % write the wave file
            %audiowrite(oFile,real(ynn),oSps,'BitsPerSample',BitsPerSample);
        end
        
        
         function generate_multiframe_signal(app)
             
             app.y=[];
             start=1;
             frames = length(app.framesDataBits)/50;
             for i = 1:frames
                 K=32;
                 data = app.framesDataBits(start:i*50);
                 data = [data zeros(1,K-1)];
                 symbols= uwspr_framer(app, data);
                 % Get complex envelop of the baseband signal
                 y=uwspr_modulater(app, symbols, app.spssymbol(app.SymMode));
              
             
            %get data
                                                                    
            
            % Generate  file 
            %
            % 2. Carrier frequency
            f = app.carrier_frequency; % Hz
            % 3. Time offset (random quiet time prefix, in seconds, in [0,9])
            toffset = app.quiet_time; % second          
            % 4. Output file sampling frequency
            oSps = app.sampling_frequency; % samples/sec (a multiple of 375, e.g. 6K, 12K, 24K, 48K, 96K)            
            % 5. Output file name (broadband signal in ".wav" format)
            oFile = int2str(f)+"_"+int2str(oSps)+".wav"; % use frequency number and sampling frequency           
            % 6. Bits per sample
            BitsPerSample = app.bits_per_sample;
            %%% up conversion
            %
            % extend the signal to [2 4 8 16 20] minutes
            z = [y zeros(1,375*app.interval(app.SymMode)*60-length(y))];
            % up sampling factor
            upSampling = oSps / 375;
            % resample the baseband signal for the ".wav" format
            x = resample(z,upSampling,1);
            % number of time points,
            xl = oSps*app.interval(app.SymMode)*60;
            % generate time points
            T = app.interval(app.SymMode)*60; % period (seconds)
            dt = T/xl; % sample period (second)
            t = 0:dt:T-dt;
            % modulation
            yy = x.*exp(-1i*(2*pi*f.*t));
            % normalize in the [-1 1] range
            yn = yy/( max( abs(min(yy)), max(yy) ) );
            % add a random noise prefix during time offset
            % r=rand(1,round(toffset*oSps)); ynn = [ r  yn];
            ynn = [zeros(1,375*toffset) yn];
             app.y= [app.y ynn];
               start=start+50;
             end


            if class(BitsPerSample) == "string" || class(BitsPerSample) =="char"
            app.player = audioplayer(real(app.y), oSps,str2num(BitsPerSample));
            else
            app.player = audioplayer(real(app.y), oSps,BitsPerSample);
            end

            % write the wave file
            %audiowrite(oFile,real(ynn),oSps,'BitsPerSample',BitsPerSample);
        end
        
        function LoadFromAppVar(app)
            app.CarrierFrequencyEditField.Value = app.carrier_frequency;
            app.FrequencySliderHz.Value = app.carrier_frequency;
            %app.OutputFileNameEditField.Value = app.output_file_name;
            app.HTML.Data = app.message;
                
        end
        
        function downConvAndBuffer(app, x)
            % in the two minute mode we are buffering four minutes
            xDown = app.dwnConv(x); % down convert
            write(app.rx_buffer,xDown);
            app.GeneralMessageswillbeshownhereLabel.Text= string(app.rx_buffer.NumUnreadSamples);
        end
        
%         function result = readBufferAndDecode(app)
%             no_samples_to_decode = 375*app.interval(app.SymMode)*60;
%             % read from layer 1 buffer
%             input_signal = peek(app.rx_buffer, no_samples_to_decode);
%             [result, cycles, output, gamma, RMS, best_lag]   = decodesignal(input_signal);
%             if result ==0
%                 %successfully decoded
%                 app.rx_decoded_data = output;
%                 %add output to result
%                 %log cycles, result, gamma, rms, and best lag
%             else
%                 %unsuccessful
%             end
%             %in case of  2 minute mode we will need to
%             %remove 9 sec worth of samples in case of 2 minute mode
%             samples_to_remove = (app.interval(app.SymMode)*60) - floor((128*app.interval(app.SymMode)*162)/375);
%             read(app.rx_buffer, samples_to_remove);
%             if length(app.rx_decoded_data)>1
%                 app.GeneralMessageswillbeshownhereLabel.Text = string(app.rx_decoded_data);
%             end
%         end
                            
    end
    
    methods (Access = private)
        
        function result = isValidSampleRateForMP4(app)
            result = false;
            if(app.sampling_frequency ==44100  || app.sampling_frequency ==48000)
                result = true;
            else
                result = false;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            SetupMode(app);
            SetupOutput(app);
            
            %setup audio device list
            info = audiodevinfo;
            numberOfDevices= audiodevinfo(0);
            devices= cell(2,numberOfDevices);            
            [devices{1,:}] = info.output.Name;
            [devices{2,:}] = info.output.ID;
            app.OutputDeviceDropDown.Items=  devices(1,:);
            app.OutputDeviceDropDown.ItemsData= devices(2,:);
            
            %input devices 
            inputDevices = cell(2, audiodevinfo(1));
            [inputDevices{1,:}] = info.input.Name;
            [inputDevices{2,:}] = info.input.ID;
            app.InputDeviceDropDown.Items= inputDevices(1,:);
            app.InputDeviceDropDown.ItemsData= inputDevices(2,:);
            
            %app.vMeter=  VolumeMeter(app.Panel);
            
        end

        % Callback function
        function ButtonPushed(app, event)
            t = 0:0.001:2;
            x = chirp(t,100,1,200,'quadratic');
            myAxe = app.UIAxes;
            [S,F,T] = spectrogram(x,128,120,128,1e3);
            imagesc(myAxe, F, T, log(abs(S'))); %plot the log spectrum
            set(myAxe,'YDir', 'normal'); % flip the Y Axis so lower frequencies are at the bottom 
            axis square 
            axis tight 
            hold
            colorbar
        end

        % Value changed function: FrequencySliderHz
        function FrequencySliderHz_2ValueChanged(app, event)
            value = app.FrequencySliderHz.Value;
            app.CarrierFrequencyEditField.Value = value;
            app.carrier_frequency = value;
        end

        % Value changing function: FrequencySliderHz
        function FrequencySliderHz_2ValueChanging(app, event)
            changingValue = event.Value;
            app.CarrierFrequencyEditField.Value = changingValue;
            app.carrier_frequency = changingValue;
        end

        % Value changed function: CarrierFrequencyEditField
        function CarrierFrequencyEditFieldValueChanged(app, event)
            value = app.CarrierFrequencyEditField.Value;
            app.FrequencySliderHz.Value= value;
            app.carrier_frequency = value;
        end

        % Menu selected function: AudioSinkMenu
        function AudioSinkMenuSelected(app, event)
                app.type_of_output=1;
                SetupOutput(app);
        end

        % Menu selected function: FileMenu
        function FileMenuSelected(app, event)
                app.OutputFileNameEditField.Visible = true;
                app.OutputFileNameEditFieldLabel.Visible=true;
                app.type_of_output=0;
                SetupOutput(app);
        end

        % Callback function
        function ModeDropDownValueChanged(app, event)
            SetupMode(app);
        end

        % Button pushed function: GenerateAudioButton
        function GenerateAudioButtonPushed(app, event)
            if app.message ~= "" 
             generate_signal(app);
            app.PlayButton.Visible ='on';
            %app.output_file_Label.Visible='on';
            app.SaveaudiofileButton.Visible='on';
            else

            end 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {543, 543};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {43, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: AdvancedSettingsMenu
        function AdvancedSettingsMenuSelected(app, event)
            %disable this menu while frame generator dialog is open 
            app.AdvancedSettingsMenu.Enable = 'off'; 
            app.settingsBtn.Enable ='off';
            app.DialogApp = frame_generator_config(app,app.sampling_frequency, app.quiet_time, app.bits_per_sample, app.output_file_type, app.type_of_output);
        end

        % Button pushed function: settingsBtn
        function settingsBtnPushed2(app, event)
            app.AdvancedSettingsMenu.Enable = 'off';    
            app.settingsBtn.Enable ='off';
            app.DialogApp = frame_generator_config(app,app.sampling_frequency, app.quiet_time, app.bits_per_sample, app.output_file_type, app.type_of_output);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            
            if(isplaying(app.player))
            pause(app.player)
            app.PlayButton.Text="Play"
            else
                play(app.player)
                app.PlayButton.Text="Pause"                
            end            
%             tt=(0:1:length(app.y)-1);
%             length(tt)
%             length(app.y)
%             plot(tt,app.y)
%             get(app.player)
        end

        % Callback function
        function OutputFileNameEditFieldValueChanged(app, event)
            value = app.OutputFileNameEditField.Value;
            app.output_file_name = value;
            
        end

        % Callback function
        function DataEditFieldValueChanging(app, event)
            changingValue = event.Value;
            match = regexp(changingValue,'([A-F]|[0-9])*', 'match', 'once')
            if isempty(match)
                if length(changingValue) >0 
                    %app.DataMessage.Text="Invalid Hex Character(s)";
                    %app.DataMessage.FontColor = "Red";
                else
                   %app.DataMessage.Text="Bits to transfer will be shown here";
                   %app.DataMessage.FontColor = "Black";
                end
            else
                   if length(changingValue) > length(match) 
                    %app.DataMessage.Text="Invalid Hex Character(s)";
                    %app.DataMessage.FontColor = "Red";
                   else
                    app.message = changingValue;
                    %app.DataMessage.Text=reshape(dec2bin(hex2dec(changingValue),4).',1,[])
                    %app.DataMessage.FontColor = [0,0.5,0];  
                   end

            end
        end

        % Data changed function: HTML
        function HTMLDataChanged(app, event)
            changingValue = app.HTML.Data;
            match = regexp(changingValue,'([A-F]|[0-9])*', 'match', 'once')
            if isempty(match)
                if length(changingValue) >0 
                    %app.DataMessage.Text="Invalid Hex Character(s)";
                    %app.DataMessage.FontColor = "Red";
                    app.Removedrightmost2bitswillbeshownhereLabel.Text="Removed right most 2 bits will be shown here";
                    app.Removedrightmost2bitswillbeshownhereLabel.FontColor="Red";
                else
                   %app.DataMessage.Text="Bits to transfer will be shown here";
                   %app.DataMessage.FontColor = "Black";
                   app.Removedrightmost2bitswillbeshownhereLabel.Text="Removed right most 2 bits will be shown here";
                    app.Removedrightmost2bitswillbeshownhereLabel.FontColor="Black";
                end
            else
                   if length(changingValue) > length(match) 
                    %app.DataMessage.Text="Invalid Hex Character(s)";
                    %app.DataMessage.FontColor = "Red";
                    app.Removedrightmost2bitswillbeshownhereLabel.Text="";
                    app.Removedrightmost2bitswillbeshownhereLabel.FontColor="Red";
                   else
                    app.message = changingValue;
                    %dataMessage=reshape(dec2bin(hex2dec(changingValue),52).',1,[]);
                    %dataMessage = regexprep(dataMessage,'^0*','')
                    %app.DataMessage.Text=dataMessage;
                   %app.DataMessage.FontColor = [0,0.5,0];  
                   %app.Removedrightmost2bitswillbeshownhereLabel.Text=dataMessage(51:52);
                   %app.Removedrightmost2bitswillbeshownhereLabel.FontColor="Red";
                   end

            end
        end

        % Menu selected function: SaveConfigurationMenu
        function SaveConfigurationMenuSelected(app, event)
         s_carrier_frequency =app.carrier_frequency;
         s_quiet_time =app.quiet_time;
         s_sampling_frequency =app.sampling_frequency; 
         s_bits_per_sample = app.bits_per_sample;
         s_output_file_name =app.output_file_name;
         s_type_of_output =app.type_of_output;
         s_output_file_type =app.output_file_type;
         s_msg=app.message;             
            uisave({'s_carrier_frequency', 's_quiet_time', 's_sampling_frequency', 's_bits_per_sample', 's_output_file_name', 's_type_of_output', 's_output_file_type', 's_msg'}, "config")            
        end

        % Menu selected function: LoadConfigurationMenu
        function LoadConfigurationMenuSelected(app, event)
         uiopen("load");
         %TODO: add a check to see if the variable exist first 
         if ~isempty(s_carrier_frequency)
         app.carrier_frequency =s_carrier_frequency;
         end
         if ~isempty(s_quiet_time)
         app.quiet_time =s_quiet_time;
         end
         if ~isempty(s_sampling_frequency)
         app.sampling_frequency =s_sampling_frequency; 
         end         
         if ~isempty(s_bits_per_sample)
         app.bits_per_sample = s_bits_per_sample;
         end
         if ~isempty(s_output_file_name)
         app.output_file_name =s_output_file_name;
         end
         if ~isempty(s_type_of_output)
         app.type_of_output =s_type_of_output;
         end
         if ~isempty(s_output_file_type)
         app.output_file_type =s_output_file_type;
         end
         if ~isempty(s_msg)
         app.message =s_msg;   
         end         
         LoadFromAppVar(app);
        end

        % Button pushed function: GenerateRandomDataButton
        function GenerateRandomDataButtonPushed(app, event)
            %generate random hex data
            randHex13 = dec2hex(randi([0 15], 1, 13));
            %assign data to global variable message
            app.message= randHex13';
            %change displayed Data
            app.HTML.Data=app.message;            
            %Update DataMessage Label and RemovedrightMost2Bits label
            dataMessage=reshape(dec2bin(hex2dec(app.message),52).',1,[]);
            %app.DataMessage.Text=dataMessage(1:50);
            %app.DataMessage.FontColor = [0,0.5,0];  
            %app.Removedrightmost2bitswillbeshownhereLabel.Text=dataMessage(51:52);
            %app.Removedrightmost2bitswillbeshownhereLabel.FontColor="Red";
        end

        % Button pushed function: SaveaudiofileButton
        function SaveaudiofileButtonPushed(app, event)
            
            if app.output_file_type==""
                app.output_file_type="wav";
            end
            fType= strcat('*.',app.output_file_type);
            filter = {char(fType)};
            [file,path] = uiputfile(filter)
            if isequal(file,0) || isequal(path,0)
             %disp('User clicked Cancel.')
            else
             %
             if class(app.bits_per_sample) == "string" || class(app.bits_per_sample) =="char"
                 if app.output_file_type == "wav" || app.output_file_type =="flac"
                     audiowrite(path+"/"+file,real(app.y),app.sampling_frequency,'BitsPerSample',str2num(app.bits_per_sample));
                 else 
                     if(app.output_file_type =="mp4")
                         if isValidSampleRateForMP4(app)==true
                           audiowrite(path+"/"+file,real(app.y),app.sampling_frequency);
                         else
                            %output invalid sampling rate message, sampling
                            %frequency used instead is 48000
                           audiowrite(path+"/"+file,real(app.y),48000);
                         end
                     else
                          audiowrite(path+"/"+file,real(app.y),app.sampling_frequency);
                     end
                 end
             else
                 if app.output_file_type == "wav" || app.output_file_type =="flac"
                     audiowrite(path+"/"+file,real(app.y),app.sampling_frequency,'BitsPerSample',app.bits_per_sample);
                 else
                      if(app.output_file_type =="mp4")
                         if isValidSampleRateForMP4(app)==true
                           audiowrite(path+"/"+file,real(app.y),app.sampling_frequency);
                         else
                            %output invalid sampling rate message, sampling
                            %frequency used instead is 48000
                           audiowrite(path+"/"+file,real(app.y),48000);
                         end
                     else
                          audiowrite(path+"/"+file,real(app.y),app.sampling_frequency);
                      end
                 end
            end
            end                       
            %app.player = audioplayer(real(app.y), oSps,BitsPerSample);
            % write the wave file
            %audiowrite(oFile,real(ynn),oSps,'BitsPerSample',BitsPerSample);
        end

        % Callback function
        function VolumeMeterHTMLDataChanged(app, event)
            %data = app.VolumeMeterHTML.Data;
            
        end

        % Value changed function: OutputDeviceDropDown
        function OutputDeviceDropDownValueChanged(app, event)
            %value = app.OutputDeviceDropDown.Value;
            app.outputAudioDeviceId = app.OutputDeviceDropDown.Value;           
            if class(app.bits_per_sample) == "string" || class(app.bits_per_sample) =="char"
            app.player = audioplayer(real(app.y), app.sampling_frequency, str2num(app.bits_per_sample), app.outputAudioDeviceId);
            else
            app.player = audioplayer(real(app.y), app.sampling_frequency, app.bits_per_sample, app.outputAudioDeviceId);
            end
        end

        % Button pushed function: LoadAudioButton
        function LoadAudioButtonPushed(app, event)
            [files, dirs] = uigetfile({'*.wav';'*.flac';'*.mp4';'*.ogg'},  'Select File(s)','MultiSelect','on');
            if isa(files, 'double')
            else
                app.files= fullfile(dirs,files);
                
                files_class= class(app.files);
                if files_class == 'cell'
                    for k=1:length(app.files)
                        if ismember(app.files{k},app.LoadedFilesListBox.Items)
                            %skip
                        else
                            app.LoadedFilesListBox.Items{end+1} = app.files{k}
                        end
                    end
                else
                            app.LoadedFilesListBox.Items{end+1} = app.files
                end
            end
          

        end

        % Button pushed function: DecodeSelectedFilesButton
        function DecodeSelectedFilesButtonPushed(app, event)
            tic
            selectedFiles =app.LoadedFilesListBox.Value;
            app.DecodingLoader.Visible='on';
            drawnow
            results={}%cell(600,20,20);
            maxDoppler=app.DopplerShiftEditField.Value;
            rrx_CarrierFrequency = app.rx_CarrierFrequency;
            rrx_sampling_rate = app.rx_sampling_rate;
            step =app.StepEditField.Value;
            UpSample=app.UpSampleEditField.Value;
            DownSample=app.DownSampleEditField.Value;
            SymmMode= app.SymMode;
            maxcycles=app.maxcyclesEditField.Value;
            for (ii=1:length(selectedFiles))
                results{ii} = decodeFile(selectedFiles{ii},maxDoppler,rrx_CarrierFrequency, rrx_sampling_rate, step ,UpSample, DownSample, SymmMode, maxcycles);
%                 counter1=1;
%                 zeroErrDecoded=false;
%                 for dcf = app.rx_CarrierFrequency - maxDoppler: app.StepEditField.Value :app.rx_CarrierFrequency+maxDoppler
%                     shouldUpsample=false;
%                     upSampleFactor=1;
%                     downSampleFactor=1;
%                     
%                     if app.UpSampleEditField.Value > 1
%                         upSampleFactor = app.UpSampleEditField.Value;
%                     end
%                     if app.DownSampleEditField.Value > 1
%                         downSampleFactor = app.DownSampleEditField.Value;
%                     end
%                     
%                     ratio = app.rx_sampling_rate/375;    %ratio is downSample factor
%                     new_sampling_rate = (app.rx_sampling_rate);
%                     gcdFactor= gcd(new_sampling_rate,375);
%                     if(gcdFactor ~= new_sampling_rate || gcdFactor ~=375)
%                         shouldUpsample=true;
%                         upSampleFactor2 = (375/gcdFactor);
%                         ratio = new_sampling_rate/gcdFactor;
%                     else
%                     end
%                     
%                     downCnv = dsp.DigitalDownConverter(...
%                         'DecimationFactor',ratio,... % yields 45000 samples (375 sps * 120 s)
%                         'SampleRate', app.rx_sampling_rate*upSampleFactor2,...
%                         'Bandwidth', 187,...
%                         'StopbandAttenuation', 55,...
%                         'PassbandRipple',0.2,...
%                         'CenterFrequency',dcf);
%                     rxBufferCap = 375*app.interval(app.SymMode)*60;
%                     rxBuffer = dsp.AsyncBuffer(rxBufferCap*2);
%                     notDecoding = true;
%                     no_samples_to_decode = 375*app.interval(app.SymMode)*60;
%                     
%                     %check if upsample is divisible by the downfactor
%                     isDivisible = ~mod(upSampleFactor,downSampleFactor);
%                     if ~isDivisible
%                         gcdUp2Ratio = gcd(upSampleFactor2*upSampleFactor, ratio);
%                         frameSize = downSampleFactor*gcdUp2Ratio;
%                     else
%                         frameSize = app.rx_sampling_rate;
%                     end
%                     
%                     
%                     
%                     afr =dsp.AudioFileReader(selectedFiles{ii},"SamplesPerFrame",frameSize);
%                     while ~isDone(afr)
%                         audioFrame = afr();
%                         if app.UpSampleEditField.Value > 1 || app.DownSampleEditField.Value >1
%                             audioFrame= resample(audioFrame,upSampleFactor,downSampleFactor);
%                         end
%                         
%                         if shouldUpsample
%                             audioFrame= resample(audioFrame,upSampleFactor2,1);
%                         end
%                         dwn_audioFrame = downCnv(audioFrame);
%                         write(rxBuffer, dwn_audioFrame);
%                         if rxBuffer.NumUnreadSamples  >= no_samples_to_decode
%                             rx_input_signal = peek(rxBuffer, no_samples_to_decode);
%                             [result, cycles, output, gamma, RMS, SixHzSNR] = decodesignal(rx_input_signal, app.SymMode);
%                             samples_to_remove = ((app.interval(app.SymMode)*60) - floor((128*app.interval(app.SymMode)*162)/375))*375;
%                             read(rxBuffer, samples_to_remove);                            
%                             results{ii,counter1,1}=selectedFiles{ii};
%                             results{ii,counter1,2}=result;
%                             results{ii,counter1,3}=output;
%                             results{ii,counter1,4}=cycles;
%                             results{ii,counter1,5}=gamma;
%                             results{ii,counter1,6}=RMS;
%                             results{ii,counter1,7}=SixHzSNR;
%                             regData = regexp(selectedFiles{ii},"(TXtime.*)_(Signal.*)_(Occur.*)_(Hp\d{2})_(Range\d*)_(PercentClip.*)_(RangeMeters\d*)_(RXtime.*)", 'tokens')
%                             
%                             [d1 d2] =size(regData);
%                             if(d2 >0)
%                                 if length(regData{1}) >1
%                                     regDt= regData{1};
%                                     if(length(regDt)==8)
%                                         for j=1 : 8
%                                             results{ii,counter1,7+j}=string(regDt(j));
%                                         end
%                                     end
%                                 end
%                             end
%                             results{ii,counter1,16}=dcf;
%                             
%                             
%                             counter1=counter1+1;
%                         end
%                     end
%                     [files, attempts, temp] = size(results);
%                     decodeSuccess=false;
%                     for nn=1 : attempts
%                         K=32; % constraint                        
%                         m=[1 1 0 1 0 1 0 0 0 0 1 0  1 1 0 0 0 1 1 1 0 0 1 1 1 1 1 0 1 0 1 1 0 0 0 0 1 1 0 1 0 0 0 1 1 0 0 0 0 1 zeros(1,K-1)];
%                         if results{ii,nn,2} == 0
%                             decodeSuccess=true;
%                             errPattern = xor(m,results{ii,nn,3});
%                             results{ii,nn,3}=-1;
%                             %number of bits in error
%                             err= sum(errPattern);
%                             if err ==0 
%                                 zeroErrDecoded  =true
%                             end
%                             %disp("Error =" + num2str(err))
%                             results{ii,nn,17}=err;
%                         else
%                             results{ii,nn,3}=-1;
%                         end
%                     end
%                     if(~decodeSuccess)
%                         disp("Failed to decode")
%                     elseif zeroErrDecoded
%                         break                        
%                     end                    
%                 end
            end
            fclose all;
            fileID = fopen('outputFile.csv', 'a');
            fprintf(fileID, 'File Name, result (0 successes | 1 failed), Output, Cycles, Gamma, RMS, SixHzSNR, Tx Time, Signal, Occur, Hp (Hydrophone), Range, Percent Clip, Range (Meters), Rx Time, Bit Error Count, carrier frequency \n');
            for k = 1: length(results)
                curr = results{k};
                currp = permute(curr, [2 1 3]);
                currp= reshape(currp, [] , size(curr,3),1); 
                [a,b] =  size(currp)
            for i=1: a
                    File_Name=currp{i,1};
                    result=currp{i,2};
                    d_Output = currp{i,3};
                    Cycles=currp{i,4};                    
                    Gamma=currp{i,5};
                    RMS=currp{i,6};
                    SixHzSNR=currp{i,7};
                    TxTime_= currp{i,8};
                    Signal_= currp{i,9};
                    Occur_= currp{i,10};
                    Hp_= currp{i,11};
                    Range_= currp{i,12};
                    PercentClip_= currp{i,13};
                    RangeMeters_= currp{i,14};
                    RxTime= currp{i,15};
                    center_of_freq = currp{i,16};
                    if currp{i,2} == 0
                        BitsInError = string(currp{i,17});
                    else
                        BitsInError ="-1";
                    end
                    fprintf(fileID, '%s, %d, %s, %d, %f, %f, %f, %s, %s, %s, %s, %s, %s, %s, %s, %s, %f \n', ...
                        File_Name, result, d_Output, Cycles, Gamma, RMS, SixHzSNR, TxTime_, Signal_, Occur_, ...
                        Hp_, Range_, PercentClip_, RangeMeters_, ...
                        RxTime, BitsInError, center_of_freq);
            end
                                fprintf(fileID,'\n');
            end
            
            
            fclose(fileID);
            
            %out=reshape(permute(results,[1 2 3]),size(results,3),[])'
            %writecell(out,"testOutput.csv");
            app.DecodingLoader.Visible='off';
            drawnow
            toc
        end

        % Button pushed function: ReceiveDecodeButton
        function ReceiveDecodeButtonPushed(app, event)
            app.isrecording=true;  
            app.overruns=[];
            f=@decodesignal;
            notActive=true;
            fn= parallel.FevalFuture;
            app.ReceiveDecodeButton.Enable ='Off';
            app.StopReceivingDecodingButton.Enable ='on';
                recorder =  audioDeviceReader( ...
                                           'SamplesPerFrame',256*4, ...
                                           'SampleRate',app.rx_sampling_rate);
                if app.isrecording
                                ratio = app.rx_sampling_rate/375           
                                app.dwnConv = dsp.DigitalDownConverter(...
                'DecimationFactor',ratio,... % yields 45000 samples (375 sps * 120 s)
                'SampleRate', app.rx_sampling_rate,...
                'Bandwidth', 187,...
                'StopbandAttenuation', 55,...
                'PassbandRipple',0.2,...
                'CenterFrequency',app.rx_CarrierFrequency);
                 bufferCap = 375*app.interval(app.SymMode)*60;
                 app.rx_buffer = dsp.AsyncBuffer(bufferCap*2);                                
                    while(app.isrecording)
                        %reads from layer 2 buffer
                        [samples, overrun] = recorder();
                        if overrun > 0 
                            %disp('overrun!');
                            app.overruns(end+1)= overrun;
                        end
                        %downCoverts to 375 samples/second and buffer the samples  
                        app.downConvAndBuffer(samples);
                        %allow this while loop to be interruptible by using a pause                         
                        pause(.00001)
                        if app.rx_buffer.NumUnreadSamples  >= 375*app.interval(app.SymMode)*60 && notActive
                            %try to decode..... but start an async operation and  continue reading from audiodevice
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            % MOVE TO A SEPARATE FUNCTION
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            no_samples_to_decode = 375*app.interval(app.SymMode)*60;
                            % read from layer 1 buffer
                            input_signal = peek(app.rx_buffer, no_samples_to_decode); 
                            %z=complex(zeros(450000,1));
                            %[result, cycles, output, gamma, RMS, best_lag]   = decodesignal(input_signal);
                             fn =parfeval(f,6,input_signal, app.SymMode);
                             
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            %Move to another async function that checks for result
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                             if result ==1
%                                 %successfully decoded 
%                                 app.rx_decoded_data = output;
%                                 %add output to result
%                                 %log cycles, result, gamma, rms, and best lag                                
%                             else
%                                 %unsuccessful                                 
%                             end
%                             %in case of  2 minute mode we will need to
%                             %remove 9 sec worth of samples in case of 2 minute mode
%                             samples_to_remove = (app.interval(app.SymMode)*60) - floor((128*app.interval(app.SymMode)*162)/375);
%                             read(app.rx_buffer, samples_to_remove);
%                             if length(app.rx_decoded_data)>1
%                             app.GeneralMessageswillbeshownhereLabel.Text = string(app.rx_decoded_data);
%                             end
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            notActive=false;
                        end    
                         if  ~notActive && fn.State == "finished"
                             disp(fn.State(1))                                                                                   
                             [result, cycles, output, gamma, RMS, SixHzSNR] = fetchOutputs(fn);
                             disp(result);
                             disp(output);
                             notActive=true;
                             samples_to_remove = ((app.interval(app.SymMode)*60) - floor((128*app.interval(app.SymMode)*162)/375))*375;
                             disp(app.rx_buffer.NumUnreadSamples);
                             read(app.rx_buffer, samples_to_remove);
                             disp(app.rx_buffer.NumUnreadSamples);                             
                         end
                        %disp(f)
                    end
                else
                end   
                                    %release resources
            app.ReceiveDecodeButton.Enable ='on';
            app.StopReceivingDecodingButton.Enable ='off';
            app.DecodingLoader_2.Visible='off'; 
            release(recorder);
                
        end

        % Button pushed function: removeFileFromListBox
        function removeFileFromListBoxButtonPushed(app, event)
            selectedFiles =app.LoadedFilesListBox.Value;
             for ii=1:length(selectedFiles)
                   app.LoadedFilesListBox.Items(ismember(app.LoadedFilesListBox.Items,selectedFiles{ii})) =[];  
             end
        end

        % Value changed function: OperatingModeDropDown
        function OperatingModeDropDownValueChanged(app, event)
            value = app.OperatingModeDropDown.Value;
            app.SymMode = str2num(value);
        end

        % Drop down opening function: InputDeviceDropDown
        function InputDeviceDropDownOpening(app, event)
            
        end

        % Value changed function: InputDeviceDropDown
        function InputDeviceDropDownValueChanged(app, event)
            value = app.InputDeviceDropDown.Value;
             app.inputAudioDeviceId = app.inputDeviceDropDown.Value;           
            if class(app.bits_per_sample) == "string" || class(app.bits_per_sample) =="char"
            app.player = audioplayer(real(app.y), app.sampling_frequency, str2num(app.bits_per_sample), app.inputAudioDeviceId);
            app.recorder = audiorecorder(app.rx_sampling_rate, app.rx_bits_per_sample, app.rx_number_of_channels,app.inputAudioDeviceId);
            else
            app.player = audioplayer(real(app.y), app.sampling_frequency, app.bits_per_sample, app.inputAudioDeviceId);
            end
        end

        % Value changed function: rx_sampling_rate_EditField
        function rx_sampling_rate_EditFieldValueChanged(app, event)
            value = app.rx_sampling_rate_EditField.Value;
            app.rx_sampling_rate=value;
            app.rx_sampling_rate_slider.Value = value;
            
        end

        % Value changed function: rx_sampling_rate_slider
        function rx_sampling_rate_sliderValueChanged(app, event)
            value = app.rx_sampling_rate_slider.Value;
            app.rx_sampling_rate=value;
            app.rx_sampling_rate_EditField.Value=value;
        end

        % Value changed function: rx_CarrierFrequencyEditField
        function rx_CarrierFrequencyEditFieldValueChanged(app, event)
            value = app.rx_CarrierFrequencyEditField.Value;
            app.rx_CarrierFrequency=value;
        end

        % Button pushed function: StopReceivingDecodingButton
        function StopReceivingDecodingButtonPushed(app, event)
            app.isrecording=false;
            if length(app.overruns) > 0
            app.GeneralMessageswillbeshownhereLabel.Text= string(app.overruns);
            else
                app.GeneralMessageswillbeshownhereLabel.Text = '0';
            end
            app.DecodingLoader_2.Visible='on'; 
            drawnow;
        end

        % Button pushed function: SelectALLButton
        function SelectALLButtonPushed(app, event)
               app.LoadedFilesListBox.Value= app.LoadedFilesListBox.Items;
        end

        % Button pushed function: GenerateFramesButton
        function GenerateFramesButtonPushed(app, event)
             framesCount = app.FramesSpinner.Value;    
             dataBits =[];
             for i=1:framesCount
                 dataBits=[dataBits randi([0, 1], 1,50)]
             end
             app.framesDataBits = dataBits;
             generate_multiframe_signal(app);      
            app.PlayButton.Visible ='on';      
            app.SaveaudiofileButton.Visible='on';
        end

        % Button pushed function: SaveGeneratedFramesDataButton
        function SaveGeneratedFramesDataButtonPushed(app, event)
           
            fType= strcat('*.','txt');
            filter = {char(fType)};                         
            [file,path] = uiputfile(filter)  
            if isequal(file,0) || isequal(path,0)
             %disp('User clicked Cancel.')
            else 
              fpath = strcat(path,file);
            fileID = fopen(fpath,'w');
            for j=1:length(app.framesDataBits)
            fprintf(fileID,'%d,',app.framesDataBits(j));
            end
            fclose(fileID);
            end
            
        end

        % Button pushed function: GenerateSignalsButton
        function GenerateSignalsButtonPushed(app, event)
            selectedFiles  = app.SNRListBox.Value;
            for i=1:length(selectedFiles)
                selectedfile=selectedFiles{i}
                [input,fs] = audioread(selectedfile);
                
                for j=app.minSNREditField.Value:app.stepSNREditField.Value:app.maxSNREditField.Value
                    newName=strcat(extractBefore(selectedfile,'.'),"_SNR_",num2str(j),".", extractAfter(selectedfile,'.'));
                    audiowrite(newName,awgn(input, j, 'measured'),fs)
                end
                
            end
        end

        % Button pushed function: loadsignalButton
        function loadsignalButtonPushed(app, event)
             [files, dirs] = uigetfile({'*.wav';'*.flac';'*.mp4';'*.ogg'},  'Select File(s)','MultiSelect','on');
            if isa(files, 'double')
            else
                app.SNR_files= fullfile(dirs,files);
                
                files_class= class(app.SNR_files);
                if files_class == 'cell'
                    for k=1:length(app.SNR_files)
                        if ismember(app.SNR_files{k},app.SNRListBox.Items)
                            %skip
                        else
                            app.SNRListBox.Items{end+1} = app.SNR_files{k}
                        end
                    end
                else
                            app.SNRListBox.Items{end+1} = app.SNR_files
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.RightPanel);
            app.CarrierFrequencySelectorPanel = uipanel(app.Panel);
            app.CarrierFrequencySelectorPanel.Title = 'Carrier Frequency Selector';
            app.FrequencySliderHzLabel = uilabel(app.CarrierFrequencySelectorPanel);
            app.FrequencySliderHzLabel.Text = 'Frequency Slider (Hz)';
            app.FrequencySliderHz = uislider(app.CarrierFrequencySelectorPanel);
            app.FrequencySliderHz.Limits = [0 10000];
            app.FrequencySliderHz.MajorTicks = [0 2000 4000 6000 8000 10000];
            app.FrequencySliderHz.MajorTickLabels = {'0', '2000', '4000', '6000', '8000', '1e+04'};
            app.FrequencySliderHz.ValueChangedFcn = createCallbackFcn(app, @FrequencySliderHz_2ValueChanged, true);
            app.FrequencySliderHz.ValueChangingFcn = createCallbackFcn(app, @FrequencySliderHz_2ValueChanging, true);
            app.FrequencySliderHz.Value = 209;
            app.CarrierFrequencyEditField = uieditfield(app.CarrierFrequencySelectorPanel, 'numeric');
            app.CarrierFrequencyEditField.Limits = [0 10000];
            app.CarrierFrequencyEditField.ValueDisplayFormat = '%5.2f';
            app.CarrierFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @CarrierFrequencyEditFieldValueChanged, true);
            app.CarrierFrequencyEditField.Value = 209;
            app.HzLabel = uilabel(app.CarrierFrequencySelectorPanel);
            app.HzLabel.Text = 'Hz';
            app.GenerateAudioButton = uibutton(app.Panel, 'push');
            app.GenerateAudioButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateAudioButtonPushed, true);
            app.GenerateAudioButton.Text = 'Generate Audio';
            app.settingsBtn = uibutton(app.Panel, 'push');
            app.settingsBtn.ButtonPushedFcn = createCallbackFcn(app, @settingsBtnPushed2, true);
            app.settingsBtn.Icon = 'gear3.png';
            app.settingsBtn.Text = '';
            app.PlayButton = uibutton(app.Panel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Visible = 'off';
            app.PlayButton.Text = {'Play'; ''};
            app.HTML = uihtml(app.Panel);
            app.HTML.HTMLSource = '<!DOCTYPE html><html><head></head>   <body>     <input id="inputData" maxlength="13" />         <script type="text/javascript">               function setup(htmlComponent) {         document.getElementById("inputData").addEventListener("input", function(event) {                      htmlComponent.Data =document.getElementById("inputData").value;         }); htmlComponent.addEventListener("DataChanged", function(event) {            (document.getElementById("inputData")).value= htmlComponent.Data;            });      }             </script>   </body>	</html>';
            app.HTML.DataChangedFcn = createCallbackFcn(app, @HTMLDataChanged, true);
            app.DataLabel = uilabel(app.Panel);
            app.DataLabel.Text = 'Data';
            app.Label = uilabel(app.Panel);
            app.Label.Tooltip = {'Data will be padded (left) to fit a 13 nibbles sequence. The right most two bits will be removed.'};
            app.Label.Text = '?';
            app.GenerateRandomDataButton = uibutton(app.Panel, 'push');
            app.GenerateRandomDataButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateRandomDataButtonPushed, true);
            app.GenerateRandomDataButton.Text = 'Generate Random Data';
            app.VolumeSliderLabel = uilabel(app.Panel);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.Panel);
            app.SaveaudiofileButton = uibutton(app.Panel, 'push');
            app.SaveaudiofileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveaudiofileButtonPushed, true);
            app.SaveaudiofileButton.Visible = 'off';
            app.SaveaudiofileButton.Text = 'Save audio file';
            app.OutputDeviceDropDownLabel = uilabel(app.Panel);
            app.OutputDeviceDropDownLabel.Text = 'Output Device';
            app.OutputDeviceDropDown = uidropdown(app.Panel);
            app.OutputDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputDeviceDropDownValueChanged, true);
            app.TransmitterLabel = uilabel(app.Panel);
            app.TransmitterLabel.Text = ' Transmitter';
            app.ReceiverLabel = uilabel(app.Panel);
            app.ReceiverLabel.Text = ' Receiver';
            app.ReceiveDecodeButton = uibutton(app.Panel, 'push');
            app.ReceiveDecodeButton.ButtonPushedFcn = createCallbackFcn(app, @ReceiveDecodeButtonPushed, true);
            app.ReceiveDecodeButton.Text = 'Receive & Decode';
            app.InputDeviceDropDownLabel = uilabel(app.Panel);
            app.InputDeviceDropDownLabel.Text = 'Input Device';
            app.InputDeviceDropDown = uidropdown(app.Panel);
            app.InputDeviceDropDown.DropDownOpeningFcn = createCallbackFcn(app, @InputDeviceDropDownOpening, true);
            app.InputDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @InputDeviceDropDownValueChanged, true);
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Tooltip = {'Starts audio sensing/receiving from the selected input device '};
            app.Label_2.Text = '?';
            app.Panel_2 = uipanel(app.Panel);
            app.LoadedFilesListBoxLabel = uilabel(app.Panel_2);
            app.LoadedFilesListBoxLabel.Text = 'Loaded Files';
            app.LoadedFilesListBox = uilistbox(app.Panel_2);
            app.LoadedFilesListBox.Items = {};
            app.LoadedFilesListBox.Multiselect = 'on';
            app.LoadedFilesListBox.Value = {};
            app.removeFileFromListBox = uibutton(app.Panel_2, 'push');
            app.removeFileFromListBox.ButtonPushedFcn = createCallbackFcn(app, @removeFileFromListBoxButtonPushed, true);
            app.removeFileFromListBox.Icon = 'remove.png';
            app.removeFileFromListBox.Tooltip = {'Remove file from list'};
            app.removeFileFromListBox.Text = '';
            app.DecodeSelectedFilesButton = uibutton(app.Panel_2, 'push');
            app.DecodeSelectedFilesButton.ButtonPushedFcn = createCallbackFcn(app, @DecodeSelectedFilesButtonPushed, true);
            app.DecodeSelectedFilesButton.Text = 'Decode Selected File(s)';
            app.LoadAudioButton = uibutton(app.Panel_2, 'push');
            app.LoadAudioButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAudioButtonPushed, true);
            app.LoadAudioButton.Text = 'Load Audio';
            app.SaveDecodingLogsButton = uibutton(app.Panel_2, 'push');
            app.SaveDecodingLogsButton.Text = 'Save Decoding Logs';
            app.DecodingLoader = uihtml(app.Panel_2);
            app.DecodingLoader.HTMLSource = '<!DOCTYPE html><html><head><meta name="viewport" content="width=device-width, initial-scale=1"><style>.loader {  border: 4px solid #f3f3f3;  border-radius: 50%;  border-top: 4px solid #3498db;  width: 10px;  height: 10px;  -webkit-animation: spin 2s linear infinite; /* Safari */  animation: spin 2s linear infinite;}/* Safari */@-webkit-keyframes spin {  0% { -webkit-transform: rotate(0deg); }  100% { -webkit-transform: rotate(360deg); }}@keyframes spin {  0% { transform: rotate(0deg); }  100% { transform: rotate(360deg); }}</style></head><body><div class="loader"></div></body></html>';
            app.DecodingLoader.Visible = 'off';
            app.SelectALLButton = uibutton(app.Panel_2, 'push');
            app.SelectALLButton.ButtonPushedFcn = createCallbackFcn(app, @SelectALLButtonPushed, true);
            app.SelectALLButton.Text = 'Select ALL';
            app.maxcyclesEditFieldLabel = uilabel(app.Panel_2);
            app.maxcyclesEditFieldLabel.Text = 'max cycles';
            app.maxcyclesEditField = uieditfield(app.Panel_2, 'numeric');
            app.maxcyclesEditField.Limits = [0 Inf];
            app.maxcyclesEditField.Value = 150;
            app.bitspersampleDropDownLabel = uilabel(app.Panel);
            app.bitspersampleDropDownLabel.Text = 'bits per sample';
            app.rx_bits_per_sample_DropDown = uidropdown(app.Panel);
            app.ReceiverSamplingFrequencyPanel = uipanel(app.Panel);
            app.ReceiverSamplingFrequencyPanel.Title = 'Receiver Sampling Frequency';
            app.rx_sampling_rate_slider = uislider(app.ReceiverSamplingFrequencyPanel);
            app.rx_sampling_rate_slider.Limits = [1000 384000];
            app.rx_sampling_rate_slider.ValueChangedFcn = createCallbackFcn(app, @rx_sampling_rate_sliderValueChanged, true);
            app.rx_sampling_rate_slider.Value = 1000;
            app.rx_sampling_rate_EditField = uieditfield(app.ReceiverSamplingFrequencyPanel, 'numeric');
            app.rx_sampling_rate_EditField.Limits = [1000 384000];
            app.rx_sampling_rate_EditField.ValueDisplayFormat = '%.3f';
            app.rx_sampling_rate_EditField.ValueChangedFcn = createCallbackFcn(app, @rx_sampling_rate_EditFieldValueChanged, true);
            app.rx_sampling_rate_EditField.Value = 12000;
            app.CarrierFrequencyEditField_2Label = uilabel(app.Panel);
            app.CarrierFrequencyEditField_2Label.Text = 'Carrier Frequency';
            app.rx_CarrierFrequencyEditField = uieditfield(app.Panel, 'numeric');
            app.rx_CarrierFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @rx_CarrierFrequencyEditFieldValueChanged, true);
            app.rx_CarrierFrequencyEditField.Value = 209;
            app.StopReceivingDecodingButton = uibutton(app.Panel, 'push');
            app.StopReceivingDecodingButton.ButtonPushedFcn = createCallbackFcn(app, @StopReceivingDecodingButtonPushed, true);
            app.StopReceivingDecodingButton.Icon = 'stop.png';
            app.StopReceivingDecodingButton.Enable = 'off';
            app.StopReceivingDecodingButton.Text = {'Stop Receiving & Decoding'; ''};
            app.DecodingLoader_2 = uihtml(app.Panel);
            app.DecodingLoader_2.HTMLSource = '<!DOCTYPE html><html><head><meta name="viewport" content="width=device-width, initial-scale=1"><style>.loader {  border: 4px solid #f3f3f3;  border-radius: 50%;  border-top: 4px solid #3498db;  width: 10px;  height: 10px;  -webkit-animation: spin 2s linear infinite; /* Safari */  animation: spin 2s linear infinite;}/* Safari */@-webkit-keyframes spin {  0% { -webkit-transform: rotate(0deg); }  100% { -webkit-transform: rotate(360deg); }}@keyframes spin {  0% { transform: rotate(0deg); }  100% { transform: rotate(360deg); }}</style></head><body><div class="loader"></div></body></html>';
            app.DecodingLoader_2.Visible = 'off';
            app.UpSampleEditFieldLabel = uilabel(app.Panel);
            app.UpSampleEditFieldLabel.Text = 'UpSample';
            app.UpSampleEditField = uieditfield(app.Panel, 'numeric');
            app.UpSampleEditField.Limits = [1 Inf];
            app.UpSampleEditField.Value = 1;
            app.DownSampleEditFieldLabel = uilabel(app.Panel);
            app.DownSampleEditFieldLabel.Text = 'DownSample';
            app.DownSampleEditField = uieditfield(app.Panel, 'numeric');
            app.DownSampleEditField.Limits = [1 Inf];
            app.DownSampleEditField.Value = 1;
            app.DopplerShiftEditFieldLabel = uilabel(app.Panel);
            app.DopplerShiftEditFieldLabel.Text = 'Doppler Shift';
            app.DopplerShiftEditField = uieditfield(app.Panel, 'numeric');
            app.StepEditFieldLabel = uilabel(app.Panel);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.Panel, 'numeric');
            app.StepEditField.Limits = [0.1 Inf];
            app.StepEditField.Value = 0.1;
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Tooltip = {'Only available for file decoding. This is the max doppler shift expected.'};
            app.Label_4.Text = '?';
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Tooltip = {'Only available for file decoding. This is the step size for center of frequency search. '};
            app.Label_5.Text = '?';
            app.FramesSpinnerLabel = uilabel(app.Panel);
            app.FramesSpinnerLabel.Text = 'Frames';
            app.FramesSpinner = uispinner(app.Panel);
            app.FramesSpinner.Limits = [1 Inf];
            app.FramesSpinner.Value = 1;
            app.GenerateFramesButton = uibutton(app.Panel, 'push');
            app.GenerateFramesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFramesButtonPushed, true);
            app.GenerateFramesButton.Text = 'Generate Frames';
            app.OperatingModeDropDownLabel = uilabel(app.Panel);
            app.OperatingModeDropDownLabel.Text = 'Operating Mode';
            app.OperatingModeDropDown = uidropdown(app.Panel);
            app.OperatingModeDropDown.Items = {'1', '2', '3', '4', '5'};
            app.OperatingModeDropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.OperatingModeDropDown.ValueChangedFcn = createCallbackFcn(app, @OperatingModeDropDownValueChanged, true);
            app.OperatingModeDropDown.Value = '1';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Tooltip = {'In an increasing order, with mode 5 being the highest mode, this modifies the symbol time to be greater.'};
            app.Label_3.Text = '?';
            app.SaveGeneratedFramesDataButton = uibutton(app.Panel, 'push');
            app.SaveGeneratedFramesDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGeneratedFramesDataButtonPushed, true);
            app.SaveGeneratedFramesDataButton.Text = 'Save Generated Frames Data';
            app.AddSNRPanel = uipanel(app.Panel);
            app.AddSNRPanel.Title = 'Add SNR';
            app.loadsignalButton = uibutton(app.AddSNRPanel, 'push');
            app.loadsignalButton.ButtonPushedFcn = createCallbackFcn(app, @loadsignalButtonPushed, true);
            app.loadsignalButton.Text = 'load signal';
            app.minSNREditField = uieditfield(app.AddSNRPanel, 'numeric');
            app.minSNREditField.Value = 1;
            app.maxSNREditField = uieditfield(app.AddSNRPanel, 'numeric');
            app.maxSNREditField.Value = 50;
            app.stepSNREditField = uieditfield(app.AddSNRPanel, 'numeric');
            app.stepSNREditField.Value = 5;
            app.GenerateSignalsButton = uibutton(app.AddSNRPanel, 'push');
            app.GenerateSignalsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSignalsButtonPushed, true);
            app.GenerateSignalsButton.Text = 'Generate Signals';
            app.minSNRLabel = uilabel(app.AddSNRPanel);
            app.minSNRLabel.Text = 'min SNR ';
            app.maxSNRLabel = uilabel(app.AddSNRPanel);
            app.maxSNRLabel.Text = 'max SNR ';
            app.stepSNRLabel = uilabel(app.AddSNRPanel);
            app.stepSNRLabel.Text = 'step SNR ';
            app.SNRListBox = uilistbox(app.AddSNRPanel);
            app.SNRListBox.Items = {};
            app.SNRListBox.Multiselect = 'on';
            app.SNRListBox.Value = {};
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu';
            app.SaveConfigurationMenu = uimenu(app.Menu);
            app.SaveConfigurationMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveConfigurationMenuSelected, true);
            app.SaveConfigurationMenu.Text = 'Save Configuration';
            app.LoadConfigurationMenu = uimenu(app.Menu);
            app.LoadConfigurationMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadConfigurationMenuSelected, true);
            app.LoadConfigurationMenu.Text = 'Load Configuration';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.ChooseOutputMenu = uimenu(app.SettingsMenu);
            app.ChooseOutputMenu.Visible = 'off';
            app.ChooseOutputMenu.Enable = 'off';
            app.ChooseOutputMenu.Text = 'Choose Output';
            app.FileMenu = uimenu(app.ChooseOutputMenu);
            app.FileMenu.MenuSelectedFcn = createCallbackFcn(app, @FileMenuSelected, true);
            app.FileMenu.Text = 'File';
            app.AudioSinkMenu = uimenu(app.ChooseOutputMenu);
            app.AudioSinkMenu.MenuSelectedFcn = createCallbackFcn(app, @AudioSinkMenuSelected, true);
            app.AudioSinkMenu.Text = 'Audio Sink';
            app.AdvancedSettingsMenu = uimenu(app.SettingsMenu);
            app.AdvancedSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @AdvancedSettingsMenuSelected, true);
            app.AdvancedSettingsMenu.Text = 'Advanced Settings';
            app.Menu3 = uimenu(app.UIFigure);
            app.Menu3.Text = 'Menu3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = oceanus

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end